classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Przyspieszeniesamochodums2EditField  matlab.ui.control.NumericEditField
        Przyspieszeniesamochodums2EditFieldLabel  matlab.ui.control.Label
        Maksymalneprzyspieszeniems2EditField  matlab.ui.control.NumericEditField
        Maksymalneprzyspieszeniems2EditFieldLabel  matlab.ui.control.Label
        KrytycznyczasdokolizjisEditField  matlab.ui.control.NumericEditField
        KrytycznyczasdokolizjisEditFieldLabel  matlab.ui.control.Label
        StaaczasowaradarusEditField     matlab.ui.control.NumericEditField
        StaaczasowaradarusEditFieldLabel  matlab.ui.control.Label
        CzstotliwoprbkowaniaHzEditField  matlab.ui.control.NumericEditField
        CzstotliwoprbkowaniaHzEditFieldLabel  matlab.ui.control.Label
        ZasigradarumEditField           matlab.ui.control.NumericEditField
        ZasigradarumEditFieldLabel      matlab.ui.control.Label
        DystansmEditField               matlab.ui.control.NumericEditField
        DystansmEditFieldLabel          matlab.ui.control.Label
        PrdkopojazduprzedmsEditField    matlab.ui.control.NumericEditField
        PrdkopojazduprzedmsEditFieldLabel  matlab.ui.control.Label
        PrdkopocztkowamsEditField       matlab.ui.control.NumericEditField
        PrdkopocztkowamsEditFieldLabel  matlab.ui.control.Label
        UruchomsymulacjButton           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UruchomsymulacjButton
        function UruchomsymulacjButtonPushed(app, event)
            model = 'ADAS_System.slx';
            model = Simulink.SimulationInput(model);
            
            model = model.setVariable('v_ego', app.PrdkopocztkowamsEditField.Value);
            model = model.setVariable('v_lead', app.PrdkopojazduprzedmsEditField.Value);
            model = model.setVariable('d_init', app.DystansmEditField.Value);
            model = model.setVariable('radar_max_range', app.ZasigradarumEditField.Value);
            model = model.setVariable('fs', app.CzstotliwoprbkowaniaHzEditField.Value);
            model = model.setVariable('tau_radar', app.StaaczasowaradarusEditField.Value);
            model = model.setVariable('ttc_threshold', app.KrytycznyczasdokolizjisEditField.Value);
            model = model.setVariable('a_ego', app.Przyspieszeniesamochodums2EditField.Value);
            model = model.setVariable('a_max', app.Maksymalneprzyspieszeniems2EditField.Value);

            simOut = sim(model);
            
            ax = cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            
            labels = {'warning', 'braking', 'TTC','prog czasowy','distance', 'a ego', 'driver reaction'};
            colours = {'k', 'r', 'g', 'b', 'm', 'r', 'g'}; 
            
            for index = 1:7
                signal = simOut.yout.getElement(index).Values;
                plot(ax, signal.Time, signal.Data,"Color",colours{index},DisplayName=labels{index});
            end
            
            hold(ax, 'off');
            legend(ax);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Czas [s]')
            ylabel(app.UIAxes, 'Parametry')
            zlabel(app.UIAxes, 'Z')
            app.UruchomsymulacjButton = uibutton(app.UIFigure, 'push');
            app.UruchomsymulacjButton.ButtonPushedFcn = createCallbackFcn(app, @UruchomsymulacjButtonPushed, true);
            app.UruchomsymulacjButton.Text = 'Uruchom symulację';
            app.PrdkopocztkowamsEditFieldLabel = uilabel(app.UIFigure);
            app.PrdkopocztkowamsEditFieldLabel.Text = 'Prędkość początkowa [m/s]';
            app.PrdkopocztkowamsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrdkopocztkowamsEditField.Limits = [0 250];
            app.PrdkopocztkowamsEditField.Value = 30;
            app.PrdkopojazduprzedmsEditFieldLabel = uilabel(app.UIFigure);
            app.PrdkopojazduprzedmsEditFieldLabel.Text = 'Prędkość pojazdu przed [m/s]';
            app.PrdkopojazduprzedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrdkopojazduprzedmsEditField.Limits = [0 250];
            app.PrdkopojazduprzedmsEditField.Value = 25;
            app.DystansmEditFieldLabel = uilabel(app.UIFigure);
            app.DystansmEditFieldLabel.Text = 'Dystans [m]';
            app.DystansmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DystansmEditField.Limits = [0 Inf];
            app.DystansmEditField.Value = 160;
            app.ZasigradarumEditFieldLabel = uilabel(app.UIFigure);
            app.ZasigradarumEditFieldLabel.Text = 'Zasięg radaru [m]';
            app.ZasigradarumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZasigradarumEditField.Limits = [0 1000];
            app.ZasigradarumEditField.Value = 150;
            app.CzstotliwoprbkowaniaHzEditFieldLabel = uilabel(app.UIFigure);
            app.CzstotliwoprbkowaniaHzEditFieldLabel.Text = 'Częstotliwość próbkowania [Hz]';
            app.CzstotliwoprbkowaniaHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CzstotliwoprbkowaniaHzEditField.Limits = [0 Inf];
            app.CzstotliwoprbkowaniaHzEditField.Value = 100;
            app.StaaczasowaradarusEditFieldLabel = uilabel(app.UIFigure);
            app.StaaczasowaradarusEditFieldLabel.Text = 'Stała czasowa radaru [s]';
            app.StaaczasowaradarusEditField = uieditfield(app.UIFigure, 'numeric');
            app.StaaczasowaradarusEditField.Limits = [0 Inf];
            app.StaaczasowaradarusEditField.Value = 0.02;
            app.KrytycznyczasdokolizjisEditFieldLabel = uilabel(app.UIFigure);
            app.KrytycznyczasdokolizjisEditFieldLabel.Text = 'Krytyczny czas do kolizji [s]';
            app.KrytycznyczasdokolizjisEditField = uieditfield(app.UIFigure, 'numeric');
            app.KrytycznyczasdokolizjisEditField.Limits = [0 Inf];
            app.KrytycznyczasdokolizjisEditField.Value = 1.5;
            app.Maksymalneprzyspieszeniems2EditFieldLabel = uilabel(app.UIFigure);
            app.Maksymalneprzyspieszeniems2EditFieldLabel.Text = 'Maksymalne przyspieszenie [m/s^2]';
            app.Maksymalneprzyspieszeniems2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Maksymalneprzyspieszeniems2EditField.Limits = [0 1000];
            app.Maksymalneprzyspieszeniems2EditField.Value = 10;
            app.Przyspieszeniesamochodums2EditFieldLabel = uilabel(app.UIFigure);
            app.Przyspieszeniesamochodums2EditFieldLabel.Text = 'Przyspieszenie samochodu [m/s^2]';
            app.Przyspieszeniesamochodums2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Przyspieszeniesamochodums2EditField.Limits = [0 1000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Associate the Simulink Model
            app.Simulation = simulation('ADAS_System');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end